classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        YawEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        Theta7EditField             matlab.ui.control.NumericEditField
        Theta7EditFieldLabel        matlab.ui.control.Label
        Theta6EditField             matlab.ui.control.NumericEditField
        Theta6EditFieldLabel        matlab.ui.control.Label
        Theta5EditField             matlab.ui.control.NumericEditField
        Theta5EditFieldLabel        matlab.ui.control.Label
        Theta4EditField             matlab.ui.control.NumericEditField
        Theta4EditFieldLabel        matlab.ui.control.Label
        Theta3EditField             matlab.ui.control.NumericEditField
        Theta3Label                 matlab.ui.control.Label
        Theta2EditField             matlab.ui.control.NumericEditField
        Theta2Label                 matlab.ui.control.Label
        Theta1EditField             matlab.ui.control.NumericEditField
        Theta1Label                 matlab.ui.control.Label
        GoToDefPosLabel             matlab.ui.control.Label
        lamp6                       matlab.ui.control.Lamp
        PlaceLabel                  matlab.ui.control.Label
        lamp5                       matlab.ui.control.Lamp
        MoveArmShelfLabel           matlab.ui.control.Label
        lamp4                       matlab.ui.control.Lamp
        PickLabel                   matlab.ui.control.Label
        lamp3                       matlab.ui.control.Lamp
        MoveArmConveyorLabel        matlab.ui.control.Label
        lamp2                       matlab.ui.control.Lamp
        lamp1                       matlab.ui.control.Lamp
        IdleLabel                   matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        DegLabel_7                  matlab.ui.control.Label
        DegLabel_6                  matlab.ui.control.Label
        DegLabel_5                  matlab.ui.control.Label
        DegLabel_4                  matlab.ui.control.Label
        DegLabel_3                  matlab.ui.control.Label
        DegLabel_2                  matlab.ui.control.Label
        DegLabel                    matlab.ui.control.Label
        PosiodoEndEffectorLabel     matlab.ui.control.Label
        OrientaodoEndEffectorLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end


    properties (Access = private)
        stateHandler % Instance of statesHandler
        timerObj % Timer object for updating joint values
        obj
        dh_alpha
        dh_a
        dh_d
        dh_theta
        %theta

    end

    properties (Access = public)
        %UIFigure          matlab.ui.Figure
        theta = [];
        currentState ;
        teste;
        theta1;
        theta2;
        theta3;
        theta4;
        theta5;
        theta6;
        yaw;
        pitch;
        roll;
        xe;
        ye;
        ze;

    end


    methods (Access = private)

        function startStateMachine(app, state)
            disp('Set State Machine:');
            %theta = evalin('main_program.m', 'theta');

            %theta = evalin('base', 'theta');
            %currentState = evalin('base', 'currentState');
            %disp(theta);
            %     theta1 = round(theta(1), 4);
            %     theta2 = round(theta(2), 4);
            %     theta3 = round(theta(3), 4)
            %     theta4 = round(theta(4), 4);
            %     theta5 = round(theta(5), 4);
            %     theta6 = round(theta(6), 4);
            %     %theta7 = round(theta(7), 3);
            %     theta7 = 20;
            %           %------------------------------------------------
            % % disp(app.theta);
            %  app.Theta1EditField.Value = theta1;
            %  app.Theta2EditField.Value =theta2;
            %  app.Theta3EditField.Value = theta3;
            %  app.Theta4EditField.Value = theta4;
            %
            %  app.Theta5EditField.Value = theta5;
            %  app.Theta6EditField.Value = theta6;
            %  app.Theta7EditField.Value = theta7;
            %-------------------------------------------------
            % disp(theta1);
            % disp(theta2);
            % disp(theta3);
            % disp(theta4);
            % disp(theta5);
            % disp(theta6);
            % disp(theta7);


            %check if string is empty
            %If empty put is value as 0.
            %       if (isempty(app.Theta1EditField.Value))
            %          app.Theta1EditField.Value = num2str(0);
            %      end
            %      if (isempty(app.Theta2EditField.Value))
            %          app.Theta2EditField.Value = num2str(0);
            %      end
            %      if (isempty(app.Theta3EditField.Value))
            %          app.Theta3EditField.Value = num2str(0);
            %      end
            %      if (isempty(app.Theta4EditField.Value))
            %          app.Theta4EditField.Value = num2str(0);
            %      end
            %      if (isempty(app.Theta5EditField.Value))
            %          app.Theta5EditField.Value = num2str(0);
            %      end
            %      if (isempty(app.Theta6EditField.Value))
            %          app.Theta6EditField.Value = num2str(0);
            %      end
            %       if (isempty(app.Theta6EditField.Value))
            %          app.Theta6EditField.Value = num2str(0);
            %       end
            %       if (isempty(app.Theta6EditField.Value))
            %          app.Theta6EditField.Value = num2str(0);
            %      end
            %
            % output = directKinematics(app.dh_alpha, app.dh_a, app.dh_d, app.theta);
            % XEditFieldValueChanged = output(1);
            % YEditFieldValueChanged = output(2);
            % ZEditFieldValueChanged = output(3);

            %get the values of main.m
            %app.theta = theta; % Store theta in the app's private property
            % Set the numeric edit fields with the theta values
            %app.NumericEditField1.Value = theta(1);
            % app.NumericEditField2.Value = app.theta(2);
            % app.NumericEditField3.Value = app.theta(3);
            % app.NumericEditField4.Value = app.theta(4);
            % app.NumericEditField5.Value = app.theta(5);
            % app.NumericEditField6.Value = app.theta(6);
            % app.NumericEditField7.Value = app.theta(7);
            i = 1;
            % if i==1
            %     app.lamp1.Color = [0,0,0];
            %     app.lamp2.Color = [0,0,0];
            %     app.lamp3.Color = [0,0,0];
            %     app.lamp4.Color = [0,0,0];
            %     app.lamp5.Color = [0,0,0];
            %     app.lamp6.Color = [0,0,0];
            %
            % end

        end

        function resetStateMachine(app)
            % Reset the state machine
            disp('State Machine Reset');
            app.stateHandler = []; % Clear the state handler

            % Reset the values of theta1 to theta7 to default or zero
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            app.Theta4EditField.Value = 0;
            app.Theta5EditField.Value = 0;
            app.Theta6EditField.Value = 0;
            app.Theta7EditField.Value = 0;
            app.lamp2.Color = [0,1,0];
            app.lamp3.Color = [0,1,0];
            app.lamp4.Color = [0,1,0];
            app.lamp5.Color = [0,1,0];
            app.lamp6.Color = [0,1,0];

        end


    end


    methods (Access = public)

        function states(app, teste)
            %disp(currentState);
            if teste ==  1
                app.lamp1.Color = [0,0,1];
                app.lamp2.Color = [1.00,1.00,1.00];
                app.lamp3.Color = [1.00,1.00,1.00];
                app.lamp4.Color = [1.00,1.00,1.00];
                app.lamp5.Color = [1.00,1.00,1.00];
                app.lamp6.Color = [1.00,1.00,1.00];
            end
            if teste ==  2
                app.lamp1.Color = [1.00,1.00,1.00];
                app.lamp2.Color = [0,0,1];
                app.lamp3.Color = [1.00,1.00,1.00];
                app.lamp4.Color = [1.00,1.00,1.00];
                app.lamp5.Color = [1.00,1.00,1.00];
                app.lamp6.Color = [1.00,1.00,1.00];
            end
            if  teste == 3

                app.lamp1.Color = [1,1,1];
                app.lamp2.Color = [1,1,1];
                app.lamp3.Color = [0,0,1];
                app.lamp4.Color = [1,1,1];
                app.lamp5.Color = [1,1,1];
                app.lamp6.Color = [1,1,1];
                teste = 0;
            end

            if teste == 4

                app.lamp1.Color = [1,1,1];
                app.lamp2.Color = [1,1,1];
                app.lamp3.Color = [1,1,1];
                app.lamp4.Color = [0,0,1];
                app.lamp5.Color = [1,1,1];
                app.lamp6.Color = [1,1,1];
            end
            if teste == 5

                app.lamp1.Color = [1,1,1];
                app.lamp2.Color = [1,1,1];
                app.lamp3.Color = [1,1,1];
                app.lamp4.Color = [1,1,1];
                app.lamp5.Color = [0,0,1];
                app.lamp6.Color = [1,1,1];
            end
            if teste == 6

                app.lamp1.Color = [1,1,1];
                app.lamp2.Color = [1,1,1];
                app.lamp3.Color = [1,1,1];
                app.lamp4.Color = [1,1,1];
                app.lamp5.Color = [1,1,1];
                app.lamp6.Color = [0,0,1];

            else
                return;
            end

            %
        end

        % function states(currentState)
        %     function iddle_Gui()
        %     app.lamp2.Color = [0,1,0];
        %     app.lamp1.Color = [0,0,1];
        %     app.lamp3.Color = [0,1,0];
        %     app.lamp4.Color = [0,1,0];
        %     app.lamp5.Color = [0,1,0];
        %     app.lamp6.Color = [0,1,0];
        %     end
        %
        % end


        function thetas(app, theta1, theta2, theta3, theta4, theta5, theta6, theta7)
            app.Theta1EditField.Value = double(theta1);
            app.Theta2EditField.Value = double(theta2);
            app.Theta3EditField.Value = double(theta3);
            app.Theta4EditField.Value = double(theta4);
            app.Theta5EditField.Value = double(theta5);
            app.Theta6EditField.Value = double(theta6);
            app.Theta7EditField.Value = double(theta7);
        end

        function pose_tip(app, xe, ye, ze, yaw, pitch, roll)
            app.XEditField.Value = double(xe);
            app.YEditField.Value = double(ye);
            app.ZEditField.Value = double(ze);
            app.YawEditField.Value = double(yaw);
            app.PitchEditField.Value = double(pitch);
            app.RollEditField.Value = double(roll);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)

            value= app.Theta1EditField.Value;

        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            value = app.Theta2EditField.Value;

        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            value = app.Theta3EditField.Value;

        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            value = app.Theta4EditField.Value;

        end

        % Value changed function: Theta5EditField
        function Theta5EditFieldValueChanged(app, event)
            value = app.Theta5EditField.Value;

        end

        % Value changed function: Theta6EditField
        function Theta6EditFieldValueChanged(app, event)
            value = app.Theta6EditField.Value;

        end

        % Value changed function: Theta7EditField
        function Theta7EditFieldValueChanged(app, event)
            value = app.Theta7EditField.Value;

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;

        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;

        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;

        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;

        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;

        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetStateMachine();
        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            delete(app);
            run('main_program.m');
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'kUKA_SFUNDO.png');
            app.OrientaodoEndEffectorLabel = uilabel(app.UIFigure);
            app.OrientaodoEndEffectorLabel.Text = 'Orientação do End-Effector';
            app.PosiodoEndEffectorLabel = uilabel(app.UIFigure);
            app.PosiodoEndEffectorLabel.Text = 'Posição do End-Effector';
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = 'Deg';
            app.DegLabel_2 = uilabel(app.UIFigure);
            app.DegLabel_2.Text = 'Deg';
            app.DegLabel_3 = uilabel(app.UIFigure);
            app.DegLabel_3.Text = 'Deg';
            app.DegLabel_4 = uilabel(app.UIFigure);
            app.DegLabel_4.Text = 'Deg';
            app.DegLabel_5 = uilabel(app.UIFigure);
            app.DegLabel_5.Text = 'Deg';
            app.DegLabel_6 = uilabel(app.UIFigure);
            app.DegLabel_6.Text = 'Deg';
            app.DegLabel_7 = uilabel(app.UIFigure);
            app.DegLabel_7.Text = 'Deg';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.IdleLabel = uilabel(app.UIFigure);
            app.IdleLabel.Text = 'Idle';
            app.lamp1 = uilamp(app.UIFigure);
            app.lamp2 = uilamp(app.UIFigure);
            app.MoveArmConveyorLabel = uilabel(app.UIFigure);
            app.MoveArmConveyorLabel.Text = 'MoveArmConveyor';
            app.lamp3 = uilamp(app.UIFigure);
            app.PickLabel = uilabel(app.UIFigure);
            app.PickLabel.Text = 'Pick';
            app.lamp4 = uilamp(app.UIFigure);
            app.MoveArmShelfLabel = uilabel(app.UIFigure);
            app.MoveArmShelfLabel.Text = 'MoveArmShelf';
            app.lamp5 = uilamp(app.UIFigure);
            app.PlaceLabel = uilabel(app.UIFigure);
            app.PlaceLabel.Text = 'Place';
            app.lamp6 = uilamp(app.UIFigure);
            app.GoToDefPosLabel = uilabel(app.UIFigure);
            app.GoToDefPosLabel.Text = 'GoToDefPos';
            app.Theta1Label = uilabel(app.UIFigure);
            app.Theta1Label.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2Label = uilabel(app.UIFigure);
            app.Theta2Label.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3Label = uilabel(app.UIFigure);
            app.Theta3Label.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta 5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.ValueChangedFcn = createCallbackFcn(app, @Theta5EditFieldValueChanged, true);
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta 6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.ValueChangedFcn = createCallbackFcn(app, @Theta6EditFieldValueChanged, true);
            app.Theta7EditFieldLabel = uilabel(app.UIFigure);
            app.Theta7EditFieldLabel.Text = 'Theta 7';
            app.Theta7EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta7EditField.ValueChangedFcn = createCallbackFcn(app, @Theta7EditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end